classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ApplyButton             matlab.ui.control.Button
        ApplyStatus             matlab.ui.control.Label
        ResetSensorInformation  matlab.ui.control.Button
        SensorResetStatus       matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        S1Tab                   matlab.ui.container.Tab
        S1Label                 matlab.ui.control.Label
        S1UnitsOutputLabel      matlab.ui.control.Label
        S1UnitsALabel           matlab.ui.control.Label
        S1UnitsBLabel           matlab.ui.control.Label
        S1EnableSensor          matlab.ui.control.CheckBox
        TypeDropDownLabel       matlab.ui.control.Label
        S1TypeDropDown          matlab.ui.control.DropDown
        OutputLabel             matlab.ui.control.Label
        S1EditFieldOutput       matlab.ui.control.NumericEditField
        aLabel                  matlab.ui.control.Label
        S1EditFieldA            matlab.ui.control.NumericEditField
        bLabel                  matlab.ui.control.Label
        S1EditFieldB            matlab.ui.control.NumericEditField
        S2Tab                   matlab.ui.container.Tab
        S2UnitsBLabel           matlab.ui.control.Label
        S2UnitsALabel           matlab.ui.control.Label
        S2UnitsOutputLabel      matlab.ui.control.Label
        S2Label                 matlab.ui.control.Label
        S2EnableSensor          matlab.ui.control.CheckBox
        bLabel_2                matlab.ui.control.Label
        S2EditFieldB            matlab.ui.control.NumericEditField
        aLabel_2                matlab.ui.control.Label
        S2EditFieldA            matlab.ui.control.NumericEditField
        OutputLabel_2           matlab.ui.control.Label
        S2EditFieldOutput       matlab.ui.control.NumericEditField
        TypeDropDownLabel_2     matlab.ui.control.Label
        S2TypeDropDown          matlab.ui.control.DropDown
        PumprateEditFieldLabel  matlab.ui.control.Label
        PumprateEditField       matlab.ui.control.NumericEditField
        PumprateUnitsLabel      matlab.ui.control.Label
        SelectdeviceLabel       matlab.ui.control.Label
        SelectCOMPort           matlab.ui.control.DropDown
        RefreshCOMPorts         matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        S1Graph                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        serialPortArduino % Description
    end

    methods (Access = private)
    
        function sensorDropDownValueChanged(app, sensor, dropdown, EditField, unitsOutput, FieldA, FieldB)
            value = dropdown.Value;
            if strcmp(value,'Analogue') % compare dropdown value
                EditField.Enable = 'on';
                unitsOutput.Text = 'Volt';
                unitsOutput.Visible = 'on';
                FieldA.Enable = 'on';
                FieldB.Enable = 'on';
            elseif strcmp(value,'Digital') % compare dropdown value
                EditField.Enable = 'on';
                unitsOutput.Text = 'P/min';
                FieldA.Enable = 'off';
                FieldB.Enable = 'off';
            else % error case
                warning('Sensor '+ sensor + ' dropdown error!');
            end
        end % SensorDropDownValueChanged

        function success = connectToArduino(app, comPort)
            success = true;
            if ~setSerialPort(comPort)
                success = false;
            end
            
            if ~openSerialChannel()
                success = false;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ApplyStatus.Visible = 'off';
            app.SensorResetStatus.Visible = 'off';
            assignin('base', 'app', app);
        end

        % Value changed function: S1TypeDropDown
        function S1TypeDropDownValueChanged(app, event)
            sensorDropDownValueChanged(app, ...
                '1', ...
                app.S1TypeDropDown, ...
                app.S1EditFieldOutput, ...
                app.S1UnitsOutputLabel, ...
                app.S1EditFieldA, ...
                app.S1EditFieldB);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            success = true;
            if app.S1EnableSensor.Value
                if strcmp(app.S1TypeDropDown.Value, 'Analogue') % compare dropdown value
                     if ~setSensor(app.S1TypeDropDown.Value, 1, app.S1EditFieldOutput.Value, ...
                             app.S1EditFieldA.Value, app.S1EditFieldB.Value)
                         success = false; % only set success to false when a failure occurs.
                     end
                elseif strcmp(app.S1TypeDropDown.Value, 'Digital') % compare dropdown value
                    if ~setSensor(app.S1TypeDropDown.Value, 1, app.S1EditFieldOutput.Value)
                        success = false; % only set success to false when a failure occurs.
                    end
                end
            end
            
            if app.S2EnableSensor.Value
                if strcmp(app.S2TypeDropDown.Value, 'Analogue') % compare dropdown value
                     if ~setSensor(app.S2TypeDropDown.Value, 2, app.S2EditFieldOutput.Value, ...
                             app.S2EditFieldA.Value, app.S2EditFieldB.Value)
                         success = false; % only set success to false when a failure occurs.
                     end
                elseif strcmp(app.S2TypeDropDown.Value, 'Digital') % compare dropdown value
                    if ~setSensor(app.S2TypeDropDown.Value, 2, app.S2EditFieldOutput.Value)
                        success = false; % only set success to false when a failure occurs.
                    end
                end
            end
            
            if ~setPumps(app.PumprateEditField.Value)
                success = false;
            end
            
            connectToArduino(app, app.SelectCOMPort.Value);
            
            app.ApplyStatus.Visible = 'on';
            if success
                app.ApplyStatus.Text = 'Success!';
            else
                app.ApplyStatus.Text = 'Failed!';
            end
        end

        % Value changed function: S2TypeDropDown
        function S2TypeDropDownValueChanged(app, event)
            sensorDropDownValueChanged(app, ...
                '2', ...
                app.S2TypeDropDown, ...
                app.S2EditFieldOutput, ...
                app.S2UnitsOutputLabel, ...
                app.S2EditFieldA, ...
                app.S2EditFieldB);
        end

        % Value changed function: S1EnableSensor
        function S1EnableSensorValueChanged(app, event)
            if app.S1EnableSensor.Value
                app.S1TypeDropDown.Enable = 'on';
                app.S1EditFieldOutput.Enable = 'on';
                app.S1EditFieldB.Enable = 'on';
                app.S1EditFieldA.Enable = 'on';
            else
                app.S1TypeDropDown.Enable = 'off';
                app.S1EditFieldOutput.Enable = 'off';
                app.S1EditFieldB.Enable = 'off';
                app.S1EditFieldA.Enable = 'off';
            end
        end

        % Value changed function: S2EnableSensor
        function S2EnableSensorValueChanged(app, event)
            if app.S2EnableSensor.Value
                app.S2TypeDropDown.Enable = 'on';
                app.S2EditFieldOutput.Enable = 'on';
                app.S2EditFieldB.Enable = 'on';
                app.S2EditFieldA.Enable = 'on';
            else
                app.S2TypeDropDown.Enable = 'off';
                app.S2EditFieldOutput.Enable = 'off';
                app.S2EditFieldB.Enable = 'off';
                app.S2EditFieldA.Enable = 'off';
            end
        end

        % Button pushed function: ResetSensorInformation
        function ResetSensorInformationButtonPushed(app, event)
            app.SensorResetStatus.Visible = 'on';
            if evalin('base', 'exist(''Sensors'')')
                evalin('base', 'clear setSensor'); % Delete the previous sensor information
                evalin('base', 'clear Sensors'); % Delete the previous sensor information
                if evalin('base', 'exist(''Sensors'')')
                    app.SensorResetStatus.Text = 'Failed!';
                    warning('Failed to remove old instance of sensor information.');
                else
                    app.SensorResetStatus.Text = 'Success!';
                end
            else
                app.SensorResetStatus.Text = 'Success!';
            end
        end

        % Button pushed function: RefreshCOMPorts
        function RefreshCOMPortsButtonPushed(app, event)
            serialDevices = seriallist;
            numberOfDevices = size(serialDevices,2);
            if numberOfDevices < 1
                app.SelectCOMPort.Items = {'No devices'};
            else
                for i = 1:numberOfDevices
                   tempCell{i} = convertStringsToChars(serialDevices(i));
                end
                app.SelectCOMPort.Items = tempCell;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            status = evalin('base', 'exist(''arduinoSerial'')');
            if status % Close if the channel has been created.
                evalin('base', 'fclose(arduinoSerial)'); % Close the channel that communicates with Arduino, can still open afterwards.
                evalin('base', 'delete(arduinoSerial)'); % Releases the channel such that the COM port can be used again.
                evalin('base', 'clear(''arduinoSerial'')'); % Removes the variable from the workspace since it cannot be opened again after delete.
            end
            status = evalin('base', 'exist(''sensorData'')');
            if status % Only write to file when sensor data has been collected.
                tempString = strcat('csvwrite(','''../Output/', datestr(now, 'yyyy-mm-dd_HHMMSS'), '_sensorData.csv'', sensorData);');
                evalin('base', tempString);
                evalin('base', 'clear(''sensorData'')');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            StopButtonPushed(app, event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ApplyStatus = uilabel(app.UIFigure);
            app.ApplyStatus.Text = 'Success!';
            app.ResetSensorInformation = uibutton(app.UIFigure, 'push');
            app.ResetSensorInformation.ButtonPushedFcn = createCallbackFcn(app, @ResetSensorInformationButtonPushed, true);
            app.ResetSensorInformation.Text = 'Reset sensors';
            app.SensorResetStatus = uilabel(app.UIFigure);
            app.SensorResetStatus.Text = 'Success!';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.S1Tab = uitab(app.TabGroup);
            app.S1Tab.Title = 'S1';
            app.S1Label = uilabel(app.S1Tab);
            app.S1Label.Text = 'Sensor 1';
            app.S1UnitsOutputLabel = uilabel(app.S1Tab);
            app.S1UnitsOutputLabel.Text = 'Volt';
            app.S1UnitsALabel = uilabel(app.S1Tab);
            app.S1UnitsALabel.Text = 'V/unit';
            app.S1UnitsBLabel = uilabel(app.S1Tab);
            app.S1UnitsBLabel.Text = 'Volt';
            app.S1EnableSensor = uicheckbox(app.S1Tab);
            app.S1EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S1EnableSensorValueChanged, true);
            app.S1EnableSensor.Text = 'Enable';
            app.S1EnableSensor.Value = true;
            app.TypeDropDownLabel = uilabel(app.S1Tab);
            app.TypeDropDownLabel.Text = 'Type:';
            app.S1TypeDropDown = uidropdown(app.S1Tab);
            app.S1TypeDropDown.Items = {'Analogue', 'Digital'};
            app.S1TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @S1TypeDropDownValueChanged, true);
            app.S1TypeDropDown.Value = 'Analogue';
            app.OutputLabel = uilabel(app.S1Tab);
            app.OutputLabel.Text = 'Output:';
            app.S1EditFieldOutput = uieditfield(app.S1Tab, 'numeric');
            app.S1EditFieldOutput.Limits = [0 Inf];
            app.aLabel = uilabel(app.S1Tab);
            app.aLabel.Text = 'a:';
            app.S1EditFieldA = uieditfield(app.S1Tab, 'numeric');
            app.S1EditFieldA.Limits = [0 Inf];
            app.bLabel = uilabel(app.S1Tab);
            app.bLabel.Text = 'b:';
            app.S1EditFieldB = uieditfield(app.S1Tab, 'numeric');
            app.S1EditFieldB.Limits = [0 Inf];
            app.S2Tab = uitab(app.TabGroup);
            app.S2Tab.Title = 'S2';
            app.S2UnitsBLabel = uilabel(app.S2Tab);
            app.S2UnitsBLabel.Text = 'Volt';
            app.S2UnitsALabel = uilabel(app.S2Tab);
            app.S2UnitsALabel.Text = 'V/unit';
            app.S2UnitsOutputLabel = uilabel(app.S2Tab);
            app.S2UnitsOutputLabel.Text = 'Volt';
            app.S2Label = uilabel(app.S2Tab);
            app.S2Label.Text = 'Sensor 2';
            app.S2EnableSensor = uicheckbox(app.S2Tab);
            app.S2EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S2EnableSensorValueChanged, true);
            app.S2EnableSensor.Text = 'Enable';
            app.S2EnableSensor.Value = true;
            app.bLabel_2 = uilabel(app.S2Tab);
            app.bLabel_2.Text = 'b:';
            app.S2EditFieldB = uieditfield(app.S2Tab, 'numeric');
            app.S2EditFieldB.Limits = [0 Inf];
            app.aLabel_2 = uilabel(app.S2Tab);
            app.aLabel_2.Text = 'a:';
            app.S2EditFieldA = uieditfield(app.S2Tab, 'numeric');
            app.S2EditFieldA.Limits = [0 Inf];
            app.OutputLabel_2 = uilabel(app.S2Tab);
            app.OutputLabel_2.Text = 'Output:';
            app.S2EditFieldOutput = uieditfield(app.S2Tab, 'numeric');
            app.S2EditFieldOutput.Limits = [0 Inf];
            app.TypeDropDownLabel_2 = uilabel(app.S2Tab);
            app.TypeDropDownLabel_2.Text = 'Type:';
            app.S2TypeDropDown = uidropdown(app.S2Tab);
            app.S2TypeDropDown.Items = {'Analogue', 'Digital'};
            app.S2TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @S2TypeDropDownValueChanged, true);
            app.S2TypeDropDown.Value = 'Analogue';
            app.PumprateEditFieldLabel = uilabel(app.UIFigure);
            app.PumprateEditFieldLabel.Text = 'Pump rate:';
            app.PumprateEditField = uieditfield(app.UIFigure, 'numeric');
            app.PumprateUnitsLabel = uilabel(app.UIFigure);
            app.PumprateUnitsLabel.Text = 'L/min';
            app.SelectdeviceLabel = uilabel(app.UIFigure);
            app.SelectdeviceLabel.Text = 'Select device:';
            app.SelectCOMPort = uidropdown(app.UIFigure);
            app.SelectCOMPort.Items = {'No devices'};
            app.SelectCOMPort.Value = 'No devices';
            app.RefreshCOMPorts = uibutton(app.UIFigure, 'push');
            app.RefreshCOMPorts.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMPortsButtonPushed, true);
            app.RefreshCOMPorts.Text = 'Refresh';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.S1Graph = uiaxes(app.UIFigure);
            title(app.S1Graph, 'Flow')
            xlabel(app.S1Graph, 't')
            ylabel(app.S1Graph, 'L/min')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Pressure')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'mmHg')
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end